classdef MuscleAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MuscleAnalysisFigure           matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewVideoMenu                   matlab.ui.container.Menu
        SelectOtherResultsMenu         matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveAllMenu                    matlab.ui.container.Menu
        SaveResultsMenu                matlab.ui.container.Menu
        SaveFramesMenu                 matlab.ui.container.Menu
        VideoMenu                      matlab.ui.container.Menu
        PlayMenu                       matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        FasciaLimitsMenu               matlab.ui.container.Menu
        LongitudinalAxisMenu           matlab.ui.container.Menu
        MiddlePointMenu                matlab.ui.container.Menu
        SummaryTextAreaLabel           matlab.ui.control.Label
        SummaryTextArea                matlab.ui.control.TextArea
        MeasurementyellowlinepixelSliderLabel  matlab.ui.control.Label
        MeasurementyellowlinepixelSlider  matlab.ui.control.Slider
        FrameSliderLabel               matlab.ui.control.Label
        FrameSlider                    matlab.ui.control.Slider
        MuscleThicknessVariationmmTextAreaLabel  matlab.ui.control.Label
        MuscleThicknessVariationmmTextArea  matlab.ui.control.TextArea
        MuscleThicknessMeanmmTextAreaLabel  matlab.ui.control.Label
        MuscleThicknessMeanmmTextArea  matlab.ui.control.TextArea
        Muscle_image                   matlab.ui.control.UIAxes
        MuscleThickness_image          matlab.ui.control.UIAxes
        LongitudinalPlot               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        eco_matrix = [];
        eco_img_real_size = [];
        memoria_fascia_sup_inf = [];
        Results = [];
        Muscle_x_pixel = [];
        Value_MT = [];
        Muscle_frame = 1;
    end
    
    methods (Access = private)
        
        function plot_eco_MT(app)
            %Plotear eco con la linea donde se realiza la medida y MT vs frame
            %eco
            imshow(app.eco_matrix(:,:,app.Muscle_frame),app.eco_img_real_size, 'Parent', app.Muscle_image)
            hold(app.Muscle_image, 'on')
                plot(app.Muscle_image,[app.Muscle_x_pixel app.Muscle_x_pixel] * app.Results.Scale_factor,[app.memoria_fascia_sup_inf(app.Muscle_frame,app.Muscle_x_pixel,2) app.memoria_fascia_sup_inf(app.Muscle_frame,app.Muscle_x_pixel,1)],'yo','LineWidth',3)
                plot(app.Muscle_image,[app.Muscle_x_pixel app.Muscle_x_pixel] * app.Results.Scale_factor,[app.memoria_fascia_sup_inf(app.Muscle_frame,app.Muscle_x_pixel,2) app.memoria_fascia_sup_inf(app.Muscle_frame,app.Muscle_x_pixel,1)],'y-','LineWidth',3)
                if app.FasciaLimitsMenu.Checked
                    plot(app.Muscle_image,(1:size(app.eco_matrix(:,:,app.Muscle_frame),2)) .* app.Results.Scale_factor, app.memoria_fascia_sup_inf(app.Muscle_frame,:,1),'r:','LineWidth',2)
                    plot(app.Muscle_image,(1:size(app.eco_matrix(:,:,app.Muscle_frame),2)) .* app.Results.Scale_factor, app.memoria_fascia_sup_inf(app.Muscle_frame,:,2),'r:','LineWidth',2)
                end 
            hold(app.Muscle_image, 'off')
            
            %MT vs frame
            app.Value_MT(:,3) = (app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,1));
            plot(app.MuscleThickness_image,(1:size(app.memoria_fascia_sup_inf,1)),app.Value_MT(:,3),'LineWidth',2) 
            hold(app.MuscleThickness_image, 'on')
                xline(app.MuscleThickness_image,app.Muscle_frame,'--', 'Color', [0.93,0.69,0.13]);
                xline(app.MuscleThickness_image, app.Results.AtRest_frames(2),'--','Color',[0.5,0.25,0]);
                xline(app.MuscleThickness_image, app.Results.UnderStimulation_frames(1),'--', 'Color',[0.5,0.25,0]);
                xline(app.MuscleThickness_image, app.Results.UnderStimulation_frames(2),'--', 'Color',[0.5,0.25,0]);
                plot(app.MuscleThickness_image,app.Results.AtRest_frames,[app.Results.AtRest_mean_mm app.Results.AtRest_mean_mm],'Color', [0.47,0.67,0.19]);
                plot(app.MuscleThickness_image,[app.Results.AtRest_frames(2) app.Results.UnderStimulation_frames(2)],[app.Results.AtRest_mean_mm app.Results.AtRest_mean_mm],'--', 'Color', [0.47,0.67,0.19]);
                plot(app.MuscleThickness_image,app.Results.UnderStimulation_frames,[app.Results.UnderStimulation_mean_mm app.Results.UnderStimulation_mean_mm], 'Color', [0.47,0.67,0.19]);
                plot(app.MuscleThickness_image,[app.Results.AtRest_frames(1) app.Results.UnderStimulation_frames(1)],[app.Results.UnderStimulation_mean_mm app.Results.UnderStimulation_mean_mm],'--', 'Color', [0.47,0.67,0.19]);
                plot(app.MuscleThickness_image,ones(2,1) .* round(mean(app.Results.AtRest_frames)), [app.Results.UnderStimulation_mean_mm app.Results.AtRest_mean_mm],'k*');
                plot(app.MuscleThickness_image,ones(2,1) .* round(mean(app.Results.AtRest_frames)), [app.Results.UnderStimulation_mean_mm app.Results.AtRest_mean_mm],'k');
                text(app.MuscleThickness_image,round(mean(app.Results.AtRest_frames)),mean([app.Results.UnderStimulation_mean_mm app.Results.AtRest_mean_mm]),[num2str(app.Results.UnderStimulation_mean_mm - app.Results.AtRest_mean_mm) '[mm]']);
            hold(app.MuscleThickness_image, 'off')
            
            %MT vs longitudinal axis
            meanMTvslongitudinalaxis = mean(app.memoria_fascia_sup_inf(app.Muscle_frame,:,2) - app.memoria_fascia_sup_inf(app.Muscle_frame,:,1));
            plot(app.LongitudinalPlot,(1:size(app.memoria_fascia_sup_inf, 2)) .* app.Results.Scale_factor, (app.memoria_fascia_sup_inf(app.Muscle_frame,:,2) - app.memoria_fascia_sup_inf(app.Muscle_frame,:,1)),'LineWidth',2)
            hold(app.LongitudinalPlot, 'on')
                xline(app.LongitudinalPlot,app.Muscle_x_pixel * app.Results.Scale_factor,'y-.','LineWidth',2);
                yline(app.LongitudinalPlot, meanMTvslongitudinalaxis, '--', 'Color', [0.47,0.67,0.19])
            hold(app.LongitudinalPlot, 'off')
            
            app.MuscleThicknessMeanmmTextArea.Value = num2str(meanMTvslongitudinalaxis);
            
        end
        
        
        function SummaryTextDisplay(app)
            app.SummaryTextArea.Value = {  ['Video Name: ', app.Results.Name] ...
                             , '' ...
                             , ['Duration [s]: ', num2str(app.Results.Duration)] ...
                             , ['Frame Rate [fps]: ', num2str(app.Results.Frame_rate)] ...
                             , ['Scale Factor [mm/pixel]: ', num2str(app.Results.Scale_factor)] ...
                             , '' ...
                             , ['Image center [pixel]: ', num2str(app.Results.Muscle_longitudinal_middle_point)] ...
                             , ['Measurement (yellow line) [pixel]: ', num2str(app.Results.Muscle_x_pixel)] ...
                             , '' ...
                             , 'At Rest:' ...
                             , ['- Start Frame: ', num2str(app.Results.AtRest_frames(1))] ...
                             , ['- End Frame: ', num2str(app.Results.AtRest_frames(2))] ...
                             , ['- Duration [s]: ', num2str(app.Results.AtRest_duration_s)] ...
                             , ['- Mean [mm]: ', num2str(app.Results.AtRest_mean_mm)] ...
                             , ['- Variance [mm^2]: ', num2str(app.Results.AtRest_variance_mm2)]...
                             , '' ...
                             , 'Rise:' ...
                             , ['-Start Frame: ', num2str(app.Results.Rise_frames(1))] ...
                             , ['-End Frame: ', num2str(app.Results.Rise_frames(2))] ...
                             , ['- Duration [s]: ', num2str(app.Results.Rise_duration_s)] ...
                             , '' ...
                             , 'Under Stimulation:' ...
                             , ['- Start Frame: ', num2str(app.Results.UnderStimulation_frames(1))] ...
                             , ['- End Frame: ', num2str(app.Results.UnderStimulation_frames(2))] ...
                             , ['- Duration [s]: ', num2str(app.Results.UnderStimulation_duration_s)] ...
                             , ['- Mean [mm]: ', num2str(app.Results.UnderStimulation_mean_mm)] ...
                             , ['- Variance [mm^2]: ', num2str(app.Results.UnderStimulation_variance_mm2)]};
                         
            %Mucle Thickness Variation
            app.MuscleThicknessVariationmmTextArea.Value = num2str(app.Results.UnderStimulation_mean_mm - app.Results.AtRest_mean_mm);
            app.MuscleThicknessMeanmmTextArea.Value = num2str(mean(app.memoria_fascia_sup_inf(app.Muscle_frame,:,2) - app.memoria_fascia_sup_inf(app.Muscle_frame,:,1)));
        end


        function PlotEcoPlay(app)
            %Animación del funcionamiento del sistema
            app.VideoMenu.Enable = false;
            app.MeasurementyellowlinepixelSlider.Enable = 0;
            app.FrameSlider.Enable = 0;
            Pause_t = 0.5/app.Results.Frame_rate;
            Value_MT_plot = app.Value_MT(:,3);
            for frame = 1:size(app.eco_matrix,3)
                app.FrameSlider.Value = frame;
                imshow(app.eco_matrix(:,:,frame),app.eco_img_real_size, 'Parent', app.Muscle_image)
                hold(app.Muscle_image, 'on')
                    plot(app.Muscle_image,[app.Muscle_x_pixel app.Muscle_x_pixel] * app.Results.Scale_factor,[app.memoria_fascia_sup_inf(frame,app.Muscle_x_pixel,2) app.memoria_fascia_sup_inf(frame,app.Muscle_x_pixel,1)],'yo','LineWidth',3)
                    plot(app.Muscle_image,[app.Muscle_x_pixel app.Muscle_x_pixel] * app.Results.Scale_factor,[app.memoria_fascia_sup_inf(frame,app.Muscle_x_pixel,2) app.memoria_fascia_sup_inf(frame,app.Muscle_x_pixel,1)],'y-','LineWidth',3)
                    if app.FasciaLimitsMenu.Checked
                        plot(app.Muscle_image,(1:size(app.eco_matrix,2)) .* app.Results.Scale_factor, app.memoria_fascia_sup_inf(frame,:,1),'r:','LineWidth',2)
                        plot(app.Muscle_image,(1:size(app.eco_matrix,2)) .* app.Results.Scale_factor, app.memoria_fascia_sup_inf(frame,:,2),'r:','LineWidth',2)
                    end
                hold(app.Muscle_image, 'off')
                
                plot(app.MuscleThickness_image,(1:size(app.memoria_fascia_sup_inf,1)),Value_MT_plot,'LineWidth',2) 
                hold(app.MuscleThickness_image, 'on')
                    xline(app.MuscleThickness_image,frame,'--', 'Color', [0.93,0.69,0.13]);
                    xline(app.MuscleThickness_image, app.Results.AtRest_frames(2),'--','Color',[0.5,0.25,0]);
                    xline(app.MuscleThickness_image, app.Results.UnderStimulation_frames(1),'--', 'Color',[0.5,0.25,0]);
                    xline(app.MuscleThickness_image, app.Results.UnderStimulation_frames(2),'--', 'Color',[0.5,0.25,0]);
                hold(app.MuscleThickness_image, 'off')
                
                %MT vs longitudinal axis
                plot(app.LongitudinalPlot,(1:size(app.memoria_fascia_sup_inf, 2)) .* app.Results.Scale_factor, (app.memoria_fascia_sup_inf(frame,:,2) - app.memoria_fascia_sup_inf(frame,:,1)),'LineWidth',2)
                hold(app.LongitudinalPlot, 'on')
                    xline(app.LongitudinalPlot,app.Muscle_x_pixel * app.Results.Scale_factor,'y-.','LineWidth',2);
                hold(app.LongitudinalPlot, 'off')
                pause(Pause_t)
                
                app.MuscleThicknessMeanmmTextArea.Value = num2str(mean(app.memoria_fascia_sup_inf(frame,:,2) - app.memoria_fascia_sup_inf(frame,:,1)));
            end 
            app.MeasurementyellowlinepixelSlider.Enable = 1;
            app.FrameSlider.Enable = 1;
            app.Muscle_frame = frame;
            app.VideoMenu.Enable = true;
            app.plot_eco_MT
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.MuscleAnalysisFigure.WindowState = 'maximized';
        end

        % Value changed function: MeasurementyellowlinepixelSlider
        function MeasurementyellowlinepixelSliderValueChanged(app, event)
            value = app.MeasurementyellowlinepixelSlider.Value;
            app.Muscle_x_pixel = round(value);
            app.MeasurementyellowlinepixelSlider.Value = app.Muscle_x_pixel; 
            app.Results.Muscle_x_pixel = app.MeasurementyellowlinepixelSlider.Value;
            app.Results.AtRest_mean_mm = mean((app.memoria_fascia_sup_inf(1:app.Results.AtRest_frames(2),app.Results.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(1:app.Results.AtRest_frames(2),app.Results.Muscle_x_pixel,1)));
            app.Results.AtRest_variance_mm2 = std((app.memoria_fascia_sup_inf(1:app.Results.AtRest_frames(2),app.Results.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(1:app.Results.AtRest_frames(2),app.Results.Muscle_x_pixel,1))) ^ 2;
            app.Results.UnderStimulation_mean_mm = mean((app.memoria_fascia_sup_inf(app.Results.UnderStimulation_frames(1):app.Results.UnderStimulation_frames(2),app.Results.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(app.Results.UnderStimulation_frames(1):app.Results.UnderStimulation_frames(2),app.Results.Muscle_x_pixel,1)));
            app.Results.UnderStimulation_variance_mm2 = std((app.memoria_fascia_sup_inf(app.Results.UnderStimulation_frames(1):app.Results.UnderStimulation_frames(2),app.Results.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(app.Results.UnderStimulation_frames(1):app.Results.UnderStimulation_frames(2),app.Results.Muscle_x_pixel,1))) ^ 2;
            if app.MeasurementyellowlinepixelSlider.Value == app.Results.Muscle_longitudinal_middle_point
                app.MiddlePointMenu.Checked = true;
            else
                app.MiddlePointMenu.Checked = false;
            end
            app.SummaryTextDisplay
            app.plot_eco_MT
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = app.FrameSlider.Value;
            app.Muscle_frame = round(value);
            app.FrameSlider.Value = app.Muscle_frame;
            app.plot_eco_MT
        end

        % Menu selected function: SelectOtherResultsMenu
        function SelectOtherResultsMenuSelected(app, event)
            path_v = uigetdir('C:\Users\JohnP\OneDrive\SHARED BY ME\Lina\','Select Folder containing Results');
            drawnow;
            figure(app.MuscleAnalysisFigure)
            if path_v == 0
                return
            end
            app.eco_matrix = load(strcat(path_v,'\eco_memory.mat'));
            app.eco_matrix = app.eco_matrix.eco_memory;
            app.memoria_fascia_sup_inf = load(strcat(path_v,'\memoria_fascia_sup_inf.mat'));
            app.memoria_fascia_sup_inf = app.memoria_fascia_sup_inf.memoria_fascia_sup_inf;
            app.Results = load(strcat(path_v,'\Results.mat'));
            app.Results = app.Results.Results;
            app.eco_img_real_size = imref2d(size(app.eco_matrix(:,:,1)),app.Results.Scale_factor,app.Results.Scale_factor);
            app.Muscle_x_pixel = app.Results.Muscle_x_pixel;
            app.MeasurementyellowlinepixelSlider.Limits = [1 size(app.memoria_fascia_sup_inf,2)];
            app.MeasurementyellowlinepixelSlider.Value = app.Muscle_x_pixel;
            app.FrameSlider.Limits = [1 size(app.memoria_fascia_sup_inf,1)];
            app.FrameSlider.Value = app.Muscle_frame;
            app.Muscle_image.YLim = [0 size(app.eco_matrix,1) * app.Results.Scale_factor];
            app.Muscle_image.XLim = [0 size(app.eco_matrix,2) * app.Results.Scale_factor];
            app.plot_eco_MT
            app.Value_MT(:,1) = 1:size(app.memoria_fascia_sup_inf,1);
            app.Value_MT(:,2) = app.Value_MT(:,1) / app.Results.Frame_rate;
            app.Value_MT(:,3) = (app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,1));
            app.SummaryTextDisplay
            app.MeasurementyellowlinepixelSlider.Enable = true;
            app.FrameSlider.Enable = true;
            app.VideoMenu.Enable = true;
            app.SaveMenu.Enable = true;
            app.SettingsMenu.Enable =true;
        end

        % Menu selected function: SaveFramesMenu
        function SaveFramesMenuSelected(app, event)
            path_frames = app.Results.Folder; % Saves Frames on same folder as selected video 
            % uigetdir('C:\Users\JohnP\OneDrive\SHARED BY ME\Lina\DAY 1\','Select Folder to Save Frames Folder');
            drawnow;
            figure(app.MuscleAnalysisFigure)
            if path_frames == 0
                return
            end
            SaveFrames(app.eco_matrix,app.Results.Muscle_longitudinal_middle_point, app.Results.Name,path_frames, app.Results.Scale_factor);
            drawnow;
            figure(app.MuscleAnalysisFigure)
        end

        % Menu selected function: SaveResultsMenu
        function SaveResultsMenuSelected(app, event)
            path_results = app.Results.Folder; % Saves Results on same folder as selected video
            % uigetdir('C:\Users\JohnP\OneDrive\SHARED BY ME\Lina\DAY 1\','Select Folder to Save Results Folder');
            drawnow;
            figure(app.MuscleAnalysisFigure)
            if path_results == 0
                return
            end
            SaveResults(app.memoria_fascia_sup_inf, app.eco_matrix, app.Results, app.Value_MT, app.Results.Name, path_results);
            drawnow;
            figure(app.MuscleAnalysisFigure)
        end

        % Menu selected function: SaveAllMenu
        function SaveAllMenuSelected(app, event)
            SaveResultsMenuSelected(app, event)
            SaveFramesMenuSelected(app, event)
        end

        % Menu selected function: MiddlePointMenu
        function MiddlePointMenuSelected(app, event)
            app.MiddlePointMenu.Checked = true;
            app.MeasurementyellowlinepixelSlider.Value = app.Results.Muscle_longitudinal_middle_point;
            app.MeasurementyellowlinepixelSliderValueChanged();
        end

        % Menu selected function: NewVideoMenu
        function NewVideoMenuSelected(app, event)
            %addpath(strcat(pwd,'\Functions')); % WHY??
            %[video_name,path_v] = uigetfile('C:\Users\JohnP\OneDrive\SHARED BY ME\Lina\DAY 1\','*.*','Select Video File');
            path_v=uigetdir('C:\Users\JohnP\OneDrive\SHARED BY ME\Lina\','Select DAY Folder to Analize ALL AVI files')
            drawnow; % Update figures and process callbacks
            figure(app.MuscleAnalysisFigure)
            if path_v == 0
                return
            end
            
            % Automatically process all AVI in folder and all of the subfolders
            cd (path_v);
            d=dir('**/*.avi')
            for i=1:length(d)
                fn=d(i).name
                ff=strcat(d(i).folder,'\')
                
            [app.eco_matrix, app.memoria_fascia_sup_inf, app.Results] = Measure(fn,ff); %processing w/ dynamic file name and folder
            app.Value_MT = [];
            app.Muscle_frame = 1;        
            app.eco_img_real_size = imref2d(size(app.eco_matrix(:,:,1)),app.Results.Scale_factor,app.Results.Scale_factor);
            app.Muscle_x_pixel = app.Results.Muscle_x_pixel;
            app.MeasurementyellowlinepixelSlider.Limits = [1 size(app.memoria_fascia_sup_inf,2)];
            app.MeasurementyellowlinepixelSlider.Value = app.Muscle_x_pixel;
            app.FrameSlider.Limits = [1 size(app.memoria_fascia_sup_inf,1)];
            app.FrameSlider.Value = app.Muscle_frame;
            app.Muscle_image.YLim = [0 size(app.eco_matrix,1) * app.Results.Scale_factor];
            app.Muscle_image.XLim = [0 size(app.eco_matrix,2) * app.Results.Scale_factor];
            app.Value_MT(:,1) = 1:size(app.memoria_fascia_sup_inf,1);
            app.Value_MT(:,2) = app.Value_MT(:,1) / app.Results.Frame_rate;
            app.Value_MT(:,3) = (app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,1));
            app.SummaryTextDisplay
            %app.PlotEcoPlay
            app.SaveMenu.Enable = true;  
            app.SettingsMenu.Enable =true;
            %SaveAllMenuSelected(app, event) % Automatically Save Results & Frames
            SaveFramesMenuSelected(app, event) % Automatically Save Frames
            end
        end

        % Menu selected function: PlayMenu
        function PlayMenuSelected(app, event)
            app.PlotEcoPlay
        end

        % Menu selected function: FasciaLimitsMenu
        function FasciaLimitsMenuSelected(app, event)
            app.FasciaLimitsMenu.Checked = ~app.FasciaLimitsMenu.Checked;
            app.plot_eco_MT();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MuscleAnalysisFigure = uifigure('Visible', 'off');
            app.MuscleAnalysisFigure.Name = 'Muscle Analysis App';
            app.FileMenu = uimenu(app.MuscleAnalysisFigure);
            app.FileMenu.Text = 'File';
            app.NewVideoMenu = uimenu(app.FileMenu);
            app.NewVideoMenu.MenuSelectedFcn = createCallbackFcn(app, @NewVideoMenuSelected, true);
            app.NewVideoMenu.Text = 'New Video';
            app.SelectOtherResultsMenu = uimenu(app.FileMenu);
            app.SelectOtherResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectOtherResultsMenuSelected, true);
            app.SelectOtherResultsMenu.Text = 'Select Other Results';
            app.SaveMenu = uimenu(app.MuscleAnalysisFigure);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Text = 'Save';
            app.SaveAllMenu = uimenu(app.SaveMenu);
            app.SaveAllMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAllMenuSelected, true);
            app.SaveAllMenu.Text = 'Save All';
            app.SaveResultsMenu = uimenu(app.SaveMenu);
            app.SaveResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveResultsMenuSelected, true);
            app.SaveResultsMenu.Text = 'Save Results';
            app.SaveFramesMenu = uimenu(app.SaveMenu);
            app.SaveFramesMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveFramesMenuSelected, true);
            app.SaveFramesMenu.Text = 'Save Frames';
            app.VideoMenu = uimenu(app.MuscleAnalysisFigure);
            app.VideoMenu.Enable = 'off';
            app.VideoMenu.Text = 'Video';
            app.PlayMenu = uimenu(app.VideoMenu);
            app.PlayMenu.MenuSelectedFcn = createCallbackFcn(app, @PlayMenuSelected, true);
            app.PlayMenu.Text = 'Play';
            app.SettingsMenu = uimenu(app.MuscleAnalysisFigure);
            app.SettingsMenu.Enable = 'off';
            app.SettingsMenu.Text = 'Settings';
            app.ViewMenu = uimenu(app.SettingsMenu);
            app.ViewMenu.Text = 'View';
            app.FasciaLimitsMenu = uimenu(app.ViewMenu);
            app.FasciaLimitsMenu.MenuSelectedFcn = createCallbackFcn(app, @FasciaLimitsMenuSelected, true);
            app.FasciaLimitsMenu.Checked = 'on';
            app.FasciaLimitsMenu.Text = 'Fascia Limits';
            app.LongitudinalAxisMenu = uimenu(app.SettingsMenu);
            app.LongitudinalAxisMenu.Text = 'Longitudinal Axis';
            app.MiddlePointMenu = uimenu(app.LongitudinalAxisMenu);
            app.MiddlePointMenu.MenuSelectedFcn = createCallbackFcn(app, @MiddlePointMenuSelected, true);
            app.MiddlePointMenu.Text = 'Middle Point';
            app.SummaryTextAreaLabel = uilabel(app.MuscleAnalysisFigure);
            app.SummaryTextAreaLabel.Text = 'Summary';
            app.SummaryTextArea = uitextarea(app.MuscleAnalysisFigure);
            app.SummaryTextArea.Editable = 'off';
            app.MeasurementyellowlinepixelSliderLabel = uilabel(app.MuscleAnalysisFigure);
            app.MeasurementyellowlinepixelSliderLabel.Text = 'Measurement (yellow line) [pixel]';
            app.MeasurementyellowlinepixelSlider = uislider(app.MuscleAnalysisFigure);
            app.MeasurementyellowlinepixelSlider.ValueChangedFcn = createCallbackFcn(app, @MeasurementyellowlinepixelSliderValueChanged, true);
            app.MeasurementyellowlinepixelSlider.Enable = 'off';
            app.FrameSliderLabel = uilabel(app.MuscleAnalysisFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.MuscleAnalysisFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Enable = 'off';
            app.MuscleThicknessVariationmmTextAreaLabel = uilabel(app.MuscleAnalysisFigure);
            app.MuscleThicknessVariationmmTextAreaLabel.Text = 'Muscle Thickness Variation [mm]: ';
            app.MuscleThicknessVariationmmTextArea = uitextarea(app.MuscleAnalysisFigure);
            app.MuscleThicknessVariationmmTextArea.Editable = 'off';
            app.MuscleThicknessMeanmmTextAreaLabel = uilabel(app.MuscleAnalysisFigure);
            app.MuscleThicknessMeanmmTextAreaLabel.Text = 'Muscle Thickness Mean [mm]: ';
            app.MuscleThicknessMeanmmTextArea = uitextarea(app.MuscleAnalysisFigure);
            app.MuscleThicknessMeanmmTextArea.Editable = 'off';
            app.Muscle_image = uiaxes(app.MuscleAnalysisFigure);
            title(app.Muscle_image, 'Muscle')
            xlabel(app.Muscle_image, '[mm]')
            ylabel(app.Muscle_image, '[mm]')
            app.MuscleThickness_image = uiaxes(app.MuscleAnalysisFigure);
            title(app.MuscleThickness_image, 'Muscle Thickness')
            xlabel(app.MuscleThickness_image, 'Frame')
            ylabel(app.MuscleThickness_image, '[mm]')
            app.LongitudinalPlot = uiaxes(app.MuscleAnalysisFigure);
            title(app.LongitudinalPlot, 'Longitudinal Axis')
            xlabel(app.LongitudinalPlot, 'Longitudinal Axis [mm]')
            ylabel(app.LongitudinalPlot, 'Muscle Thickness [mm]')
            % Show the figure after all components are created
            app.MuscleAnalysisFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MuscleAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MuscleAnalysisFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MuscleAnalysisFigure)
        end
    end
end